classdef truckrFullAccount < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        AccountTypeFullLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        EmployeeDataButton              matlab.ui.control.Button
        ShipmentReportsButton           matlab.ui.control.Button
        VehicleMaintenanceReportButton  matlab.ui.control.Button
        MonthlyTotalMaintenanceReportButton  matlab.ui.control.Button
        ShippingManifestButton          matlab.ui.control.Button
        PurchaseOrderButton             matlab.ui.control.Button
        MonthlyPayrollReportButton      matlab.ui.control.Button
        ShippingDataButton              matlab.ui.control.Button
        MaintenanceRecordButton         matlab.ui.control.Button
        EquipmentDataButton             matlab.ui.control.Button
        YourAccountInformationButton    matlab.ui.control.Button
        LogoutButton                    matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
            %delete(app.truckrFullAccount)
            %truckrFullAccount.delete;
            app.delete;
            truckrLogin
        end

        % Button pushed function: YourAccountInformationButton
        function YourAccountInformationButtonPushed(app, event)
            app.delete;
            truckrUserAccInfo
        end

        % Button pushed function: EquipmentDataButton
        function EquipmentDataButtonPushed(app, event)
            app.delete;
            truckrEquimpentData
        end

        % Button pushed function: MaintenanceRecordButton
        function MaintenanceRecordButtonPushed(app, event)
            app.delete;
            truckrMaintenanceRecord
        end

        % Button pushed function: ShippingDataButton
        function ShippingDataButtonPushed(app, event)
            app.delete;
            truckrShippingData
        end

        % Button pushed function: MonthlyPayrollReportButton
        function MonthlyPayrollReportButtonPushed(app, event)
            app.delete;
            truckrMonthlyPayrollReport
        end

        % Button pushed function: PurchaseOrderButton
        function PurchaseOrderButtonPushed(app, event)
            app.delete;
            truckrPurchaseOrder
        end

        % Button pushed function: ShippingManifestButton
        function ShippingManifestButtonPushed(app, event)
            app.delete;
            truckrShippingManifest
        end

        % Button pushed function: MonthlyTotalMaintenanceReportButton
        function MonthlyTotalMaintenanceReportButtonPushed(app, event)
            app.delete;
            truckrMonthlyTotalMaintenanceReport
        end

        % Button pushed function: VehicleMaintenanceReportButton
        function VehicleMaintenanceReportButtonPushed(app, event)
            app.delete;
            truckrVehicleMaintenanceReport
        end

        % Button pushed function: ShipmentReportsButton
        function ShipmentReportsButtonPushed(app, event)
            app.delete;
            truckrShipmentReports
        end

        % Button pushed function: EmployeeDataButton
        function EmployeeDataButtonPushed(app, event)
            app.delete;
            truckrEmployeeData
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {485, 485};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {487, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Name of what ever was clicked?')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'TruckrLogo.jpg';
            app.AccountTypeFullLabel = uilabel(app.LeftPanel);
            app.AccountTypeFullLabel.Text = 'Account Type: Full';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = 'Tbh idk 100% if it should open new pages or show up here, idk how to do that also';
            app.RightPanel = uipanel(app.GridLayout);
            app.LogoutButton = uibutton(app.RightPanel, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Text = 'Logout';
            app.YourAccountInformationButton = uibutton(app.RightPanel, 'push');
            app.YourAccountInformationButton.ButtonPushedFcn = createCallbackFcn(app, @YourAccountInformationButtonPushed, true);
            app.YourAccountInformationButton.Text = 'Your Account Information';
            app.EquipmentDataButton = uibutton(app.RightPanel, 'push');
            app.EquipmentDataButton.ButtonPushedFcn = createCallbackFcn(app, @EquipmentDataButtonPushed, true);
            app.EquipmentDataButton.Text = 'Equipment Data';
            app.MaintenanceRecordButton = uibutton(app.RightPanel, 'push');
            app.MaintenanceRecordButton.ButtonPushedFcn = createCallbackFcn(app, @MaintenanceRecordButtonPushed, true);
            app.MaintenanceRecordButton.Text = 'Maintenance Record';
            app.ShippingDataButton = uibutton(app.RightPanel, 'push');
            app.ShippingDataButton.ButtonPushedFcn = createCallbackFcn(app, @ShippingDataButtonPushed, true);
            app.ShippingDataButton.Text = 'Shipping Data';
            app.MonthlyPayrollReportButton = uibutton(app.RightPanel, 'push');
            app.MonthlyPayrollReportButton.ButtonPushedFcn = createCallbackFcn(app, @MonthlyPayrollReportButtonPushed, true);
            app.MonthlyPayrollReportButton.Text = 'Monthly Payroll Report';
            app.PurchaseOrderButton = uibutton(app.RightPanel, 'push');
            app.PurchaseOrderButton.ButtonPushedFcn = createCallbackFcn(app, @PurchaseOrderButtonPushed, true);
            app.PurchaseOrderButton.Text = 'Purchase Order';
            app.ShippingManifestButton = uibutton(app.RightPanel, 'push');
            app.ShippingManifestButton.ButtonPushedFcn = createCallbackFcn(app, @ShippingManifestButtonPushed, true);
            app.ShippingManifestButton.Text = 'Shipping Manifest';
            app.MonthlyTotalMaintenanceReportButton = uibutton(app.RightPanel, 'push');
            app.MonthlyTotalMaintenanceReportButton.ButtonPushedFcn = createCallbackFcn(app, @MonthlyTotalMaintenanceReportButtonPushed, true);
            app.MonthlyTotalMaintenanceReportButton.Text = 'Monthly Total Maintenance Report';
            app.VehicleMaintenanceReportButton = uibutton(app.RightPanel, 'push');
            app.VehicleMaintenanceReportButton.ButtonPushedFcn = createCallbackFcn(app, @VehicleMaintenanceReportButtonPushed, true);
            app.VehicleMaintenanceReportButton.Text = 'Vehicle Maintenance Report';
            app.ShipmentReportsButton = uibutton(app.RightPanel, 'push');
            app.ShipmentReportsButton.ButtonPushedFcn = createCallbackFcn(app, @ShipmentReportsButtonPushed, true);
            app.ShipmentReportsButton.Text = 'Shipment Reports';
            app.EmployeeDataButton = uibutton(app.RightPanel, 'push');
            app.EmployeeDataButton.ButtonPushedFcn = createCallbackFcn(app, @EmployeeDataButtonPushed, true);
            app.EmployeeDataButton.Text = 'Employee Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = truckrFullAccount

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end